classdef hammer_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        TriggerSettingsPanel            matlab.ui.container.Panel
        UseTriggerCheckBox              matlab.ui.control.CheckBox
        TriggersetpointVEditFieldLabel  matlab.ui.control.Label
        TriggersetpointVEditField       matlab.ui.control.NumericEditField
        TriggerWindowsSpinnerLabel      matlab.ui.control.Label
        TriggerWindowsSpinner           matlab.ui.control.Spinner
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        TimeoutsEditField               matlab.ui.control.NumericEditField
        CollectionTimesEditFieldLabel   matlab.ui.control.Label
        CollectionTimesEditField        matlab.ui.control.NumericEditField
        SamplingFreqHzDropDownLabel     matlab.ui.control.Label
        SamplingFreqHzDropDown          matlab.ui.control.DropDown
        SystemmasskgEditFieldLabel      matlab.ui.control.Label
        SystemmasskgEditField           matlab.ui.control.NumericEditField
        ChannelSettingsPanel            matlab.ui.container.Panel
        ChannelnameEditFieldLabel       matlab.ui.control.Label
        ChannelnameEditField            matlab.ui.control.EditField
        DevicetouseDropDown             matlab.ui.control.DropDown
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        ACcouplingCheckBox              matlab.ui.control.CheckBox
        ChannelIDDropDown               matlab.ui.control.DropDown
        ExcitationcurrentAEditFieldLabel  matlab.ui.control.Label
        ExcitationcurrentAEditField     matlab.ui.control.NumericEditField
        InternalSensitivityEditFieldLabel  matlab.ui.control.Label
        InternalSensitivityEditField    matlab.ui.control.NumericEditField
        AccSensitivityVgEditFieldLabel  matlab.ui.control.Label
        AccSensitivityVgEditField       matlab.ui.control.NumericEditField
        AddChannelButton                matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        ChannelsListBoxLabel            matlab.ui.control.Label
        ChannelsListBox                 matlab.ui.control.ListBox
        RemoveSelectedChannelsButton    matlab.ui.control.Button
        ConfigurationDetailsPanel       matlab.ui.container.Panel
        TestarticleEditFieldLabel       matlab.ui.control.Label
        TestarticleEditField            matlab.ui.control.EditField
        TestEngineerEditFieldLabel      matlab.ui.control.Label
        TestEngineerEditField           matlab.ui.control.EditField
        EditField3Label                 matlab.ui.control.Label
        EditField3                      matlab.ui.control.EditField
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        AcquireDataButton               matlab.ui.control.StateButton
    end

    
    methods (Access = public)
        
        function populateDAQChannel(app, channel)
            global s;
            addAnalogInputChannel(s, channel.deviceName, channel.ID,  channel.measurementType)
            fields = fieldnames(channel.daqAttributes);
            for index = 1:length(fields)
                field = fields{index};
                if isfield(s.Channels(end), field)
                    currChannel = s.Channels(end);
                    currChannel.(field) = channel.daqAttributes.(field);
                    s.Channels(end) = currChannel;
                end
            end
        end
        
        function collectData(app, src, event)
            global collectionComplete;
            global dataBuffer;
            global timeBuffer;
            global timeoutT0;
            global t0;
            global data;
            global time;
            global timeoutDuration;
            global duration;
            
            if (toc(timeoutT0) > timeoutDuration) && app.UseTriggerCheckBox.Value
                collectionComplete = 2;
                disp('TRIGGER TIME OUT')
            end
            
            disp('packet recieved')
            if t0 == 0
                disp('Waiting for trigger event...')
                if max(max(dataBuffer)) > app.TriggersetpointVEditField.Value
                    % Start to collect data
                    t0 = tic;
                    data = [flipud(dataBuffer(timeBuffer > -1, :)); event.Data];
                    time = [flipud(timeBuffer(timeBuffer > -1)); event.TimeStamps];
                    
                else
                    shift = size(event.Data, 1);
                    dataBuffer = circshift(dataBuffer, shift);
                    dataBuffer(1:shift, :) = flipud(event.Data);
                    
                    time_shift = size(event.TimeStamps, 1);
                    timeBuffer = circshift(timeBuffer, time_shift);
                    timeBuffer(1:time_shift, :) = flipud(event.TimeStamps);
                end
            elseif toc(t0) < duration
                if isempty(time)
                    data = [data; event.Data];
                    time = [time; event.TimeStamps];
                    
                % This is a really sloppy fix to an issue where concurrent runs
                % of the data acquisition script would cause duplicate packets of
                % data to be imported. The root cause was never identified as of
                % 7/9/19
                elseif event.TimeStamps(1) > time(end)
                    data = [data; event.Data];
                    time = [time; event.TimeStamps];
                end
            else
                collectionComplete = 1;
                disp('FINISHED')
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global s;
            global Channels;
            Channels = {};
            % Start DAQ session
            s = daq.createSession('ni');
            devices = daq.getDevices;
            measurementTypes = devices(1,2).Subsystems.MeasurementTypesAvailable';
            app.MeasurementTypeDropDown.Items = measurementTypes;

            app.DevicetouseDropDown.Items = cellstr(char(devices.ID));
            
            app.ChannelIDDropDown.Items = devices(1,2).Subsystems.ChannelNames;
            
            freqs = linspace(51200, 51200/16, 16);
            app.SamplingFreqHzDropDown.Items = cellstr(strsplit(num2str(freqs)));
        end

        % Button pushed function: AddChannelButton
        function AddChannelButtonPushed(app, event)
            global Channels;
            channel = struct();
            daqAttributes = struct();
            channel.name = app.ChannelnameEditField.Value;
            
            deviceName = app.DevicetouseDropDown.Value;
            
            assert(~strcmp(deviceName, 'Device to use'), 'No valid Device found.')
            channel.deviceName = deviceName;
            
            assert(~strcmp(app.ChannelIDDropDown.Value, 'Channel ID'), 'Must select Channel!')
            channel.ID = app.ChannelIDDropDown.Value;
            
            channel.measurementType = app.MeasurementTypeDropDown.Value;
            
            daqAttributes.Sensitivity = app.AccSensitivityVgEditField.Value;
            
            if strcmp(channel.measurementType, 'IEPE')
                app.ACcouplingCheckBox.Value = 1;
                daqAttributes.ExcitationCurrent = str2double(app.ExcitationcurrentAEditField.Value);
            elseif strcmp(channel.measurementType, 'Accelerometer')
                app.ACcouplingCheckBox.Value = 0;
                daqAttributes.ExcitationSource = 'None';
            end
            
            if app.ACcouplingCheckBox.Value
                daqAttributes.Coupling = 'AC';
            else
                daqAttributes.Coupling = 'DC';
            end 
            channel.daqAttributes = daqAttributes;
            displayName = strcat('Ch: ', channel.name, {' '}, channel.deviceName, {' '}, channel.ID, {' | '}, channel.daqAttributes.Coupling);
            channel.displayName = displayName{1};
            
            channelConfig = strcat(channel.deviceName, channel.ID);
            
            allChannelNames = cellfun(@(channel) strcat(channel.deviceName, channel.ID), Channels, 'UniformOutput', false);
            if isempty(allChannelNames)
                app.ChannelsListBox.Items = {};
            end
            
            isUnique = sum(cellfun(@(name) strcmp(name, channelConfig), allChannelNames)) == 0;
            assert(isUnique, 'Duplicate channel name/configuration')
            populateDAQChannel(app, channel);
            Channels{end+1} = channel;
            app.ChannelsListBox.Items{end + 1} = channel.displayName;
        end

        % Button pushed function: RemoveSelectedChannelsButton
        function RemoveSelectedChannelsButtonPushed(app, event)
            global Channels;
            selectedChannelNames = app.ChannelsListBox.Value;
            remainingChannels = {};
            remainingNames = {};
            for i = 1:length(selectedChannelNames)
                targetName = selectedChannelNames{i};
                for j = 1:length(Channels)
                    if ~strcmp(Channels{j}.displayName, targetName)
                        remainingChannels{end+1} = Channels{j};
                        remainingNames{end+1} = targetName;
                    end
                end
            end
            Channels = remainingChannels;
            app.ChannelsListBox.Value = {};
            app.ChannelsListBox.Items = remainingNames;
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            global Channels;
            save('hammer_config.mat', 'Channels');
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            global Channels;
            global s;
            uiopen('load');
            s = daq.createSession('ni');
            
            for channel = Channels
               populateDAQChannel(app, channel) 
            end
        end

        % Value changed function: UseTriggerCheckBox
        function UseTriggerCheckBoxValueChanged(app, event)
            value = app.UseTriggerCheckBox.Value;
            if value
                app.TriggersetpointVEditField.Enable = 'on';
                app.TriggerWindowsSpinner.Enable = 'on';
                app.TimeoutsEditField.Enable = 'on';

            else
                app.TriggersetpointVEditField.Enable = 'off';
                app.TriggerWindowsSpinner.Enable = 'off';
                app.TimeoutsEditField.Enable = 'off';
            end
        end

        % Value changed function: AcquireDataButton
        function AcquireDataButtonValueChanged(app, event)
            global t0;
            global s;
            global collectionComplete;
            global time;
            global data;
            global timeBuffer;
            global dataBuffer;
            global timeoutDuration;
            global duration;
            global timeoutT0;
            
            
            collectionComplete = 0;
            time = [];
            data = [];
            s.Rate = str2double(app.SamplingFreqHzDropDown.Value);
            s.IsContinuous = 1;
            duration = app.CollectionTimesEditField.Value;
            timeoutDuration = app.TimeoutsEditField.Value;
            lh = addlistener(s, 'DataAvailable', @app.collectData);
            
            triggerWindow = app.TriggerWindowsSpinner.Value;
            dataBuffer = zeros([s.Rate * triggerWindow, length(s.Channels)]);
            timeBuffer = ones([s.Rate * triggerWindow, 1]) * -1;
            
            if app.UseTriggerCheckBox.Value
                app.AcquireDataButton.Text = 'Waiting for Trigger...';
                t0 = 0;
                disp('datosdf')
            else
                app.AcquireDataButton.Text = 'Acquiring Data...';
                disp('ddddd')
                t0 = tic;
            end
            
            timeoutT0 = tic;
            startBackground(s);
            while ~collectionComplete
                pause(1)
                disp(s.IsRunning)
            end
            stop(s);
            figure; plot(time, data)
%             plotData(time, data, handles);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.AcquisitionSettingsPanel = uipanel(app.UIFigure);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.TriggerSettingsPanel = uipanel(app.AcquisitionSettingsPanel);
            app.TriggerSettingsPanel.Title = 'Trigger Settings';
            app.UseTriggerCheckBox = uicheckbox(app.TriggerSettingsPanel);
            app.UseTriggerCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTriggerCheckBoxValueChanged, true);
            app.UseTriggerCheckBox.Text = 'Use Trigger';
            app.TriggersetpointVEditFieldLabel = uilabel(app.TriggerSettingsPanel);
            app.TriggersetpointVEditFieldLabel.Enable = 'off';
            app.TriggersetpointVEditFieldLabel.Text = 'Trigger setpoint (V)';
            app.TriggersetpointVEditField = uieditfield(app.TriggerSettingsPanel, 'numeric');
            app.TriggersetpointVEditField.Limits = [0 1];
            app.TriggersetpointVEditField.Enable = 'off';
            app.TriggerWindowsSpinnerLabel = uilabel(app.TriggerSettingsPanel);
            app.TriggerWindowsSpinnerLabel.Enable = 'off';
            app.TriggerWindowsSpinnerLabel.Text = 'Trigger Window (s)';
            app.TriggerWindowsSpinner = uispinner(app.TriggerSettingsPanel);
            app.TriggerWindowsSpinner.Step = 0.25;
            app.TriggerWindowsSpinner.Limits = [0 5];
            app.TriggerWindowsSpinner.Enable = 'off';
            app.TimeoutsEditFieldLabel = uilabel(app.TriggerSettingsPanel);
            app.TimeoutsEditFieldLabel.Enable = 'off';
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.TimeoutsEditField = uieditfield(app.TriggerSettingsPanel, 'numeric');
            app.TimeoutsEditField.Limits = [0 30];
            app.TimeoutsEditField.Enable = 'off';
            app.CollectionTimesEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.CollectionTimesEditFieldLabel.Text = 'Collection Time (s)';
            app.CollectionTimesEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.CollectionTimesEditField.Limits = [0 100];
            app.SamplingFreqHzDropDownLabel = uilabel(app.AcquisitionSettingsPanel);
            app.SamplingFreqHzDropDownLabel.Text = 'Sampling Freq (Hz)';
            app.SamplingFreqHzDropDown = uidropdown(app.AcquisitionSettingsPanel);
            app.SamplingFreqHzDropDown.Items = {'51200'};
            app.SamplingFreqHzDropDown.Value = '51200';
            app.SystemmasskgEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.SystemmasskgEditFieldLabel.Text = 'System mass (kg)';
            app.SystemmasskgEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.SystemmasskgEditField.Limits = [0 100];
            app.ChannelSettingsPanel = uipanel(app.UIFigure);
            app.ChannelSettingsPanel.Title = 'Channel Settings';
            app.ChannelnameEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.ChannelnameEditFieldLabel.Text = 'Channel name';
            app.ChannelnameEditField = uieditfield(app.ChannelSettingsPanel, 'text');
            app.DevicetouseDropDown = uidropdown(app.ChannelSettingsPanel);
            app.DevicetouseDropDown.Items = {'Device to use', ' bobby', ''};
            app.DevicetouseDropDown.Value = 'Device to use';
            app.MeasurementTypeDropDown = uidropdown(app.ChannelSettingsPanel);
            app.MeasurementTypeDropDown.Items = {'Voltage'};
            app.MeasurementTypeDropDown.Value = 'Voltage';
            app.ACcouplingCheckBox = uicheckbox(app.ChannelSettingsPanel);
            app.ACcouplingCheckBox.Text = 'AC coupling';
            app.ChannelIDDropDown = uidropdown(app.ChannelSettingsPanel);
            app.ChannelIDDropDown.Items = {'Channel ID'};
            app.ChannelIDDropDown.Value = 'Channel ID';
            app.ExcitationcurrentAEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.ExcitationcurrentAEditFieldLabel.Text = 'Excitation current (A)';
            app.ExcitationcurrentAEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.ExcitationcurrentAEditField.Limits = [0.002 0.01];
            app.ExcitationcurrentAEditField.Value = 0.002;
            app.InternalSensitivityEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.InternalSensitivityEditFieldLabel.Text = 'Internal Sensitivity';
            app.InternalSensitivityEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.InternalSensitivityEditField.Limits = [0 Inf];
            app.AccSensitivityVgEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.AccSensitivityVgEditFieldLabel.Text = 'Acc. Sensitivity (V/g)';
            app.AccSensitivityVgEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.AccSensitivityVgEditField.Limits = [0 1000];
            app.AddChannelButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.AddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelButtonPushed, true);
            app.AddChannelButton.Text = 'Add Channel';
            app.SaveConfigButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ChannelsListBoxLabel = uilabel(app.ChannelSettingsPanel);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.ChannelSettingsPanel);
            app.ChannelsListBox.Items = {'No channels'};
            app.ChannelsListBox.Multiselect = 'on';
            app.ChannelsListBox.Value = {'No channels'};
            app.RemoveSelectedChannelsButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.RemoveSelectedChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedChannelsButtonPushed, true);
            app.RemoveSelectedChannelsButton.Text = 'Remove Selected Channels';
            app.ConfigurationDetailsPanel = uipanel(app.UIFigure);
            app.ConfigurationDetailsPanel.Title = 'Configuration Details';
            app.TestarticleEditFieldLabel = uilabel(app.ConfigurationDetailsPanel);
            app.TestarticleEditFieldLabel.Text = 'Test article';
            app.TestarticleEditField = uieditfield(app.ConfigurationDetailsPanel, 'text');
            app.TestEngineerEditFieldLabel = uilabel(app.ConfigurationDetailsPanel);
            app.TestEngineerEditFieldLabel.Text = 'Test Engineer';
            app.TestEngineerEditField = uieditfield(app.ConfigurationDetailsPanel, 'text');
            app.EditField3Label = uilabel(app.ConfigurationDetailsPanel);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.ConfigurationDetailsPanel, 'text');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            app.AcquireDataButton = uibutton(app.UIFigure, 'state');
            app.AcquireDataButton.ValueChangedFcn = createCallbackFcn(app, @AcquireDataButtonValueChanged, true);
            app.AcquireDataButton.Text = 'Acquire Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hammer_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end